classdef DiagnosisSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        DiseaseTextArea                 matlab.ui.control.TextArea
        DiseaseTextAreaLabel            matlab.ui.control.Label
        PredictButton                   matlab.ui.control.Button
        SymptomsListBox                 matlab.ui.control.ListBox
        SymptomsListBoxLabel            matlab.ui.control.Label
        HearthDiseaseRiskTab            matlab.ui.container.Tab
        PredictButton_3                 matlab.ui.control.Button
        ResultEditField                 matlab.ui.control.NumericEditField
        ResultEditFieldLabel            matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        AgeEditField_3                  matlab.ui.control.NumericEditField
        AgeEditField_3Label             matlab.ui.control.Label
        HistoryofdiabetesDropDown       matlab.ui.control.DropDown
        HistoryofdiabetesDropDownLabel  matlab.ui.control.Label
        GeneralhealthDropDown           matlab.ui.control.DropDown
        GeneralhealthDropDownLabel      matlab.ui.control.Label
        DaysofgoodphysicalhealthpermonthSpinner  matlab.ui.control.Spinner
        DaysofgoodphysicalhealthpermonthSpinnerLabel  matlab.ui.control.Label
        DaysofgoodmentalhealthpermonthSpinner  matlab.ui.control.Spinner
        DaysofgoodmentalhealthpermonthSpinnerLabel  matlab.ui.control.Label
        BMIEditField_2                  matlab.ui.control.NumericEditField
        BMIEditField_2Label             matlab.ui.control.Label
        SexDropDown_2                   matlab.ui.control.DropDown
        SexDropDown_2Label              matlab.ui.control.Label
        HistoyofstrokeCheckBox          matlab.ui.control.CheckBox
        DifficultywalkingorclimbingstairsCheckBox  matlab.ui.control.CheckBox
        PhysicalactivitiyduringpastmonthCheckBox  matlab.ui.control.CheckBox
        Heavydrinking14weeklyformale7weeklyforfemaleCheckBox  matlab.ui.control.CheckBox
        Smokedmorethan100cigarettesCheckBox  matlab.ui.control.CheckBox
        HistoyofhighbloodpressureCheckBox  matlab.ui.control.CheckBox
        HistoyofhighbloodcholesterolCheckBox  matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        HeartDiseaseDiagnosisTab        matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        STslopeEditField                matlab.ui.control.NumericEditField
        STslopeEditFieldLabel           matlab.ui.control.Label
        STdepressionEditField           matlab.ui.control.NumericEditField
        STdepressionEditFieldLabel      matlab.ui.control.Label
        ExerciseinducedanginaDropDown   matlab.ui.control.DropDown
        ExerciseinducedanginaDropDownLabel  matlab.ui.control.Label
        MaximumheartrateachievedEditField  matlab.ui.control.NumericEditField
        MaximumheartrateachievedEditFieldLabel  matlab.ui.control.Label
        RestingelectrocardiographicresultsSpinner  matlab.ui.control.Spinner
        RestingelectrocardiographicresultsSpinnerLabel  matlab.ui.control.Label
        MajorvesselscoloredbyfluoroscopySpinner  matlab.ui.control.Spinner
        MajorvesselscoloredbyfluoroscopySpinnerLabel  matlab.ui.control.Label
        ThalassemiaDropDown             matlab.ui.control.DropDown
        ThalassemiaDropDownLabel        matlab.ui.control.Label
        Fastingbloodsugar120mgdlDropDown  matlab.ui.control.DropDown
        Fastingbloodsugar120mgdlDropDownLabel  matlab.ui.control.Label
        SerumCholesterolmgdlEditField   matlab.ui.control.NumericEditField
        SerumCholesterolmgdlEditFieldLabel  matlab.ui.control.Label
        ChestPainSpinner                matlab.ui.control.Spinner
        ChestPainSpinnerLabel           matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        SexDropDownLabel                matlab.ui.control.Label
        RestingbloodpressureEditField   matlab.ui.control.NumericEditField
        RestingbloodpressureEditFieldLabel  matlab.ui.control.Label
        AgeEditField_2                  matlab.ui.control.NumericEditField
        AgeEditField_2Label             matlab.ui.control.Label
        ResultEditField_2               matlab.ui.control.NumericEditField
        ResultEditField_2Label          matlab.ui.control.Label
        PredictButton_2                 matlab.ui.control.Button
        DiabetesTab                     matlab.ui.container.Tab
        ResultTextArea                  matlab.ui.control.TextArea
        ResultTextAreaLabel             matlab.ui.control.Label
        PredictButton_4                 matlab.ui.control.Button
        InsulinEditField                matlab.ui.control.NumericEditField
        InsulinEditFieldLabel           matlab.ui.control.Label
        GlucoseEditField                matlab.ui.control.NumericEditField
        GlucoseEditFieldLabel           matlab.ui.control.Label
        BloodPressureEditField          matlab.ui.control.NumericEditField
        BloodPressureEditFieldLabel     matlab.ui.control.Label
        SkinThicknessEditField          matlab.ui.control.NumericEditField
        SkinThicknessEditFieldLabel     matlab.ui.control.Label
        BMIEditField                    matlab.ui.control.NumericEditField
        BMIEditFieldLabel               matlab.ui.control.Label
        ofPregnaciesEditField           matlab.ui.control.NumericEditField
        ofPregnaciesEditFieldLabel      matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        mySymptoms
        myModel
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mySymptoms = readtable(['Datasets' filesep 'General' filesep 'Symptom-severity.csv']); % mySymptoms is a table with variables "Symptom" and "weight"
            app.SymptomsListBox.Items = app.mySymptoms.Symptom; % display array extracted from "Symptom" variable
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            selectedArray = app.SymptomsListBox.Value;
            inputTable = array2table(selectedArray);
            %diagnosis = GeneralCommunicator.returnPrediction(inputTable);
            %app.DiseaseTextArea.Value = diagnosis;
        end

        % Button pushed function: PredictButton_3
        function PredictButton_3Pushed(app, event)
            
            HeartDiseaseorAttack = [app.CheckBox.Value];
            HighBP = [app.HistoyofhighbloodpressureCheckBox.Value];
            HighChol = [app.HistoyofhighbloodcholesterolCheckBox.Value];
            BMI = [app.BMIEditField_2.Value];
            Smoker = [app.Smokedmorethan100cigarettesCheckBox.Value];
            Stroke = [app.HistoyofstrokeCheckBox.Value];
            %Diabetes = [app.HistoryofdiabetesDropDown.Value];
            switch app.HistoryofdiabetesDropDown.Value 
                case "No"
                    Diabetes = [0];
                case "Yes, during pregnancy"
                    Diabetes = [1];
                case "Yes"
                    Diabetes = [2];
                otherwise
                    Diabetes = [0];
            end
            PhysActivity = [app.DaysofgoodphysicalhealthpermonthSpinner.Value];
            HvyAlcoholConsump = [app.Heavydrinking14weeklyformale7weeklyforfemaleCheckBox.Value];
            %GenHlth = [app.GeneralhealthDropDown.Value];
            switch app.GeneralhealthDropDown.Value
                case "Excellent"
                    GenHlth = [1];
                case "Very good"
                    GenHlth = [2];
                case "Good"
                    GenHlth = [3];
                case "Fair"
                    GenHlth = [4];
                case "Poor"
                    GenHlth = [5];
                otherwise
                    GenHlth = [3];
            end
            MentHlth = [app.DaysofgoodmentalhealthpermonthSpinner.Value];
            DiffWalk = [app.DifficultywalkingorclimbingstairsCheckBox.Value];
            %Sex = [app.SexDropDown_2.Value];
            switch app.SexDropDown_2.Value
                case "Male"
                    Sex = [0];
                case "Female"
                    Sex = [1];
                otherwise
                    Sex = [0];
            end
            %Age = [app.AgeDropDown.Value];
            switch true
                case app.AgeEditField_3.Value>=18 && app.AgeEditField_3.Value<=24
                    Age = [1];
                case app.AgeEditField_3.Value>=25 && app.AgeEditField_3.Value<=29
                    Age = [2];
                case app.AgeEditField_3.Value>=30 && app.AgeEditField_3.Value<=34
                    Age = [3];
                case app.AgeEditField_3.Value>=35 && app.AgeEditField_3.Value<=39
                    Age = [4];
                case app.AgeEditField_3.Value>=40 && app.AgeEditField_3.Value<=44
                    Age = [5];
                case app.AgeEditField_3.Value>=45 && app.AgeEditField_3.Value<=49
                    Age = [6];
                case app.AgeEditField_3.Value>=50 && app.AgeEditField_3.Value<=54
                    Age = [7];
                case app.AgeEditField_3.Value>=55 && app.AgeEditField_3.Value<=59
                    Age = [8];
                case app.AgeEditField_3.Value>=60 && app.AgeEditField_3.Value<=64
                    Age = [9];
                case app.AgeEditField_3.Value>=65 && app.AgeEditField_3.Value<=69
                    Age = [10];
                case app.AgeEditField_3.Value>=70 && app.AgeEditField_3.Value<=74
                    Age = [11];
                case app.AgeEditField_3.Value>=75 && app.AgeEditField_3.Value<=79
                    Age = [12];
                case app.AgeEditField_3.Value>=80
                    Age = [13];
                otherwise
                    Age = [14];
            end
            %Parse dropdown values
            riskFactors = table(HeartDiseaseorAttack, HighBP, HighChol, BMI, Smoker, Stroke, Diabetes, ...
                PhysActivity, HvyAlcoholConsump, GenHlth, MentHlth, DiffWalk, Sex, Age);
            app.ResultEditField.Value = riskFactors{1,"Age"};
            
            
        end

        % Button pushed function: PredictButton_2
        function PredictButton_2Pushed(app, event)
            age = [app.AgeEditField_2.Value];
            %sex = [app.SexDropDown.Value];
            switch app.SexDropDown.Value
                case "Male"
                    sex = [0];
                case "Female"
                    sex = [1];
                otherwise
                    sex = [0];
            end
            cp = [app.ChestPainSpinner.Value];
            trestbps = [app.RestingbloodpressureEditField.Value];
            chol = [app.SerumCholesterolmgdlEditField.Value];
            %fbs = [app.Fastingbloodsugar120mgdlDropDown.Value];
            switch app.ThalassemiaDropDown.Value
                case "No"
                    fbs = [0];
                case "Yes"
                    fbs = [1];
                otherwise
                    fbs = [0];
            end
            restecg = [app.RestingelectrocardiographicresultsSpinner.Value];
            thalach = [app.MaximumheartrateachievedEditField.Value];
            %exang = [];
            switch app.ExerciseinducedanginaDropDown.Value
                case "No"
                    exang = [0];
                case "Yes"
                    exang = [1];
                otherwise
                    exang = [0];
            end
            oldpeak = [app.STdepressionEditField.Value];
            slope = [app.STslopeEditField.Value];
            ca = [app.MajorvesselscoloredbyfluoroscopySpinner.Value];
            %thal = [app.ThalassemiaDropDown.Value];
            switch app.ThalassemiaDropDown.Value
                case "Normal"
                    thal = [0];
                case "Fixed defect"
                    thal = [1];
                case "Reversible defect"
                    thal = [2];
                otherwise
                    thal = [0];
            end
            diagnosis = table(age, sex, cp, trestbps, chol, fbs, restecg, thalach, exang, oldpeak, slope, ca, thal);
            app.ResultEditField_2.Value = diagnosis{1,"age"};
        end

        % Button pushed function: PredictButton_4
        function PredictButton_4Pushed(app, event)
            Pregnancies = [app.ofPregnaciesEditField.Value];
            Glucose = [app.GlucoseEditField.Value];
            BloodPressure = [app.BloodPressureEditField.Value];
            SkinThickness = [app.SkinThicknessEditField.Value];
            Insulin = [app.InsulinEditField.Value];
            BMI = [app.BMIEditField.Value];
            Age = [app.AgeEditField.Value];
            
            input = table(Pregnancies, Glucose, BloodPressure, SkinThickness, Insulin, BMI, Age);
            diagnosis = DiabetesCommunicator.returnPrediction(input);
            app.ResultTextArea.Value = diagnosis;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.SymptomsListBoxLabel = uilabel(app.GeneralTab);
            app.SymptomsListBoxLabel.Text = 'Symptoms';
            app.SymptomsListBox = uilistbox(app.GeneralTab);
            app.SymptomsListBox.Items = {'A, B, C'};
            app.SymptomsListBox.Multiselect = 'on';
            app.SymptomsListBox.Value = {};
            app.PredictButton = uibutton(app.GeneralTab, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.DiseaseTextAreaLabel = uilabel(app.GeneralTab);
            app.DiseaseTextAreaLabel.Text = 'Disease';
            app.DiseaseTextArea = uitextarea(app.GeneralTab);
            app.HearthDiseaseRiskTab = uitab(app.TabGroup);
            app.HearthDiseaseRiskTab.Title = 'Hearth Disease Risk';
            app.Panel = uipanel(app.HearthDiseaseRiskTab);
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.Text = 'History of angina or coronary heart disease.';
            app.HistoyofhighbloodcholesterolCheckBox = uicheckbox(app.Panel);
            app.HistoyofhighbloodcholesterolCheckBox.Text = 'Histoy of high blood cholesterol.';
            app.HistoyofhighbloodpressureCheckBox = uicheckbox(app.Panel);
            app.HistoyofhighbloodpressureCheckBox.Text = 'Histoy of high blood pressure.';
            app.Smokedmorethan100cigarettesCheckBox = uicheckbox(app.Panel);
            app.Smokedmorethan100cigarettesCheckBox.Text = 'Smoked more than 100 cigarettes.';
            app.Heavydrinking14weeklyformale7weeklyforfemaleCheckBox = uicheckbox(app.Panel);
            app.Heavydrinking14weeklyformale7weeklyforfemaleCheckBox.Text = 'Heavy drinking (14 weekly for male, 7 weekly for female)';
            app.PhysicalactivitiyduringpastmonthCheckBox = uicheckbox(app.Panel);
            app.PhysicalactivitiyduringpastmonthCheckBox.Text = 'Physical activitiy during past month.';
            app.DifficultywalkingorclimbingstairsCheckBox = uicheckbox(app.Panel);
            app.DifficultywalkingorclimbingstairsCheckBox.Text = 'Difficulty walking or climbing stairs.';
            app.HistoyofstrokeCheckBox = uicheckbox(app.Panel);
            app.HistoyofstrokeCheckBox.Text = 'Histoy of stroke.';
            app.SexDropDown_2Label = uilabel(app.Panel);
            app.SexDropDown_2Label.Text = 'Sex';
            app.SexDropDown_2 = uidropdown(app.Panel);
            app.SexDropDown_2.Items = {'Male', 'Female'};
            app.SexDropDown_2.Value = 'Male';
            app.BMIEditField_2Label = uilabel(app.Panel);
            app.BMIEditField_2Label.Text = 'BMI';
            app.BMIEditField_2 = uieditfield(app.Panel, 'numeric');
            app.BMIEditField_2.Limits = [1 999];
            app.BMIEditField_2.Value = 1;
            app.DaysofgoodmentalhealthpermonthSpinnerLabel = uilabel(app.Panel);
            app.DaysofgoodmentalhealthpermonthSpinnerLabel.Text = 'Days of good mental health per month.';
            app.DaysofgoodmentalhealthpermonthSpinner = uispinner(app.Panel);
            app.DaysofgoodmentalhealthpermonthSpinner.Limits = [0 30];
            app.DaysofgoodphysicalhealthpermonthSpinnerLabel = uilabel(app.Panel);
            app.DaysofgoodphysicalhealthpermonthSpinnerLabel.Text = 'Days of good physical health per month.';
            app.DaysofgoodphysicalhealthpermonthSpinner = uispinner(app.Panel);
            app.DaysofgoodphysicalhealthpermonthSpinner.Limits = [0 30];
            app.GeneralhealthDropDownLabel = uilabel(app.Panel);
            app.GeneralhealthDropDownLabel.Text = 'General health';
            app.GeneralhealthDropDown = uidropdown(app.Panel);
            app.GeneralhealthDropDown.Items = {'Excellent', 'Very good', 'Good', 'Fair', 'Poor'};
            app.GeneralhealthDropDown.Value = 'Excellent';
            app.HistoryofdiabetesDropDownLabel = uilabel(app.Panel);
            app.HistoryofdiabetesDropDownLabel.Text = 'History of diabetes';
            app.HistoryofdiabetesDropDown = uidropdown(app.Panel);
            app.HistoryofdiabetesDropDown.Items = {'No', 'Yes, during pregnancy', 'Yes'};
            app.HistoryofdiabetesDropDown.Value = 'No';
            app.AgeEditField_3Label = uilabel(app.Panel);
            app.AgeEditField_3Label.Text = 'Age';
            app.AgeEditField_3 = uieditfield(app.Panel, 'numeric');
            app.AgeEditField_3.Limits = [0 200];
            app.ResultEditFieldLabel = uilabel(app.HearthDiseaseRiskTab);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.HearthDiseaseRiskTab, 'numeric');
            app.PredictButton_3 = uibutton(app.HearthDiseaseRiskTab, 'push');
            app.PredictButton_3.ButtonPushedFcn = createCallbackFcn(app, @PredictButton_3Pushed, true);
            app.PredictButton_3.Text = 'Predict';
            app.HeartDiseaseDiagnosisTab = uitab(app.TabGroup);
            app.HeartDiseaseDiagnosisTab.Title = 'Heart Disease Diagnosis';
            app.PredictButton_2 = uibutton(app.HeartDiseaseDiagnosisTab, 'push');
            app.PredictButton_2.ButtonPushedFcn = createCallbackFcn(app, @PredictButton_2Pushed, true);
            app.PredictButton_2.Text = 'Predict';
            app.ResultEditField_2Label = uilabel(app.HeartDiseaseDiagnosisTab);
            app.ResultEditField_2Label.Text = 'Result';
            app.ResultEditField_2 = uieditfield(app.HeartDiseaseDiagnosisTab, 'numeric');
            app.Panel_2 = uipanel(app.HeartDiseaseDiagnosisTab);
            app.AgeEditField_2Label = uilabel(app.Panel_2);
            app.AgeEditField_2Label.Text = 'Age';
            app.AgeEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.RestingbloodpressureEditFieldLabel = uilabel(app.Panel_2);
            app.RestingbloodpressureEditFieldLabel.Text = 'Resting blood pressure';
            app.RestingbloodpressureEditField = uieditfield(app.Panel_2, 'numeric');
            app.SexDropDownLabel = uilabel(app.Panel_2);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.Panel_2);
            app.SexDropDown.Items = {'Male', 'Female'};
            app.SexDropDown.Value = 'Male';
            app.ChestPainSpinnerLabel = uilabel(app.Panel_2);
            app.ChestPainSpinnerLabel.Text = 'Chest Pain';
            app.ChestPainSpinner = uispinner(app.Panel_2);
            app.ChestPainSpinner.Limits = [0 4];
            app.SerumCholesterolmgdlEditFieldLabel = uilabel(app.Panel_2);
            app.SerumCholesterolmgdlEditFieldLabel.Text = 'Serum Cholesterol (mg/dl)';
            app.SerumCholesterolmgdlEditField = uieditfield(app.Panel_2, 'numeric');
            app.Fastingbloodsugar120mgdlDropDownLabel = uilabel(app.Panel_2);
            app.Fastingbloodsugar120mgdlDropDownLabel.Text = 'Fasting blood sugar > 120 mg/dl';
            app.Fastingbloodsugar120mgdlDropDown = uidropdown(app.Panel_2);
            app.Fastingbloodsugar120mgdlDropDown.Items = {'No', 'Yes'};
            app.Fastingbloodsugar120mgdlDropDown.Value = 'No';
            app.ThalassemiaDropDownLabel = uilabel(app.Panel_2);
            app.ThalassemiaDropDownLabel.Text = 'Thalassemia';
            app.ThalassemiaDropDown = uidropdown(app.Panel_2);
            app.ThalassemiaDropDown.Items = {'Normal', 'FIxed defect', 'Reversible defect'};
            app.ThalassemiaDropDown.Value = 'Normal';
            app.MajorvesselscoloredbyfluoroscopySpinnerLabel = uilabel(app.Panel_2);
            app.MajorvesselscoloredbyfluoroscopySpinnerLabel.Text = 'Major vessels colored by fluoroscopy ';
            app.MajorvesselscoloredbyfluoroscopySpinner = uispinner(app.Panel_2);
            app.MajorvesselscoloredbyfluoroscopySpinner.Limits = [0 4];
            app.RestingelectrocardiographicresultsSpinnerLabel = uilabel(app.Panel_2);
            app.RestingelectrocardiographicresultsSpinnerLabel.Text = 'Resting electrocardiographic results';
            app.RestingelectrocardiographicresultsSpinner = uispinner(app.Panel_2);
            app.RestingelectrocardiographicresultsSpinner.Limits = [0 2];
            app.MaximumheartrateachievedEditFieldLabel = uilabel(app.Panel_2);
            app.MaximumheartrateachievedEditFieldLabel.Text = 'Maximum heart rate achieved';
            app.MaximumheartrateachievedEditField = uieditfield(app.Panel_2, 'numeric');
            app.ExerciseinducedanginaDropDownLabel = uilabel(app.Panel_2);
            app.ExerciseinducedanginaDropDownLabel.Text = 'Exercise induced angina';
            app.ExerciseinducedanginaDropDown = uidropdown(app.Panel_2);
            app.ExerciseinducedanginaDropDown.Items = {'No', 'Yes'};
            app.ExerciseinducedanginaDropDown.Value = 'No';
            app.STdepressionEditFieldLabel = uilabel(app.Panel_2);
            app.STdepressionEditFieldLabel.Text = 'ST depression';
            app.STdepressionEditField = uieditfield(app.Panel_2, 'numeric');
            app.STslopeEditFieldLabel = uilabel(app.Panel_2);
            app.STslopeEditFieldLabel.Text = 'ST slope';
            app.STslopeEditField = uieditfield(app.Panel_2, 'numeric');
            app.DiabetesTab = uitab(app.TabGroup);
            app.DiabetesTab.Title = 'Diabetes';
            app.AgeEditFieldLabel = uilabel(app.DiabetesTab);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.ofPregnaciesEditFieldLabel = uilabel(app.DiabetesTab);
            app.ofPregnaciesEditFieldLabel.Text = '# of Pregnacies';
            app.ofPregnaciesEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.BMIEditFieldLabel = uilabel(app.DiabetesTab);
            app.BMIEditFieldLabel.Text = 'BMI';
            app.BMIEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.SkinThicknessEditFieldLabel = uilabel(app.DiabetesTab);
            app.SkinThicknessEditFieldLabel.Text = 'Skin Thickness';
            app.SkinThicknessEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.BloodPressureEditFieldLabel = uilabel(app.DiabetesTab);
            app.BloodPressureEditFieldLabel.Text = 'Blood Pressure';
            app.BloodPressureEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.GlucoseEditFieldLabel = uilabel(app.DiabetesTab);
            app.GlucoseEditFieldLabel.Text = 'Glucose';
            app.GlucoseEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.InsulinEditFieldLabel = uilabel(app.DiabetesTab);
            app.InsulinEditFieldLabel.Text = 'Insulin';
            app.InsulinEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.PredictButton_4 = uibutton(app.DiabetesTab, 'push');
            app.PredictButton_4.ButtonPushedFcn = createCallbackFcn(app, @PredictButton_4Pushed, true);
            app.PredictButton_4.Text = 'Predict';
            app.ResultTextAreaLabel = uilabel(app.DiabetesTab);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.DiabetesTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiagnosisSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end