classdef GeneralApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PredictButton                 matlab.ui.control.Button
        SelectedSymptomsListBox       matlab.ui.control.ListBox
        SelectedSymptomsListBoxLabel  matlab.ui.control.Label
        SymptomsListBox               matlab.ui.control.ListBox
        SymptomsListBoxLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        Diagnosis % Diagnosis for Predictions
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            symptoms = readtable(['Project' filesep 'ML' filesep 'General' filesep 'Symptom-severity.csv']); % mySymptoms is a table with variables "Symptom" and "weight"
            app.SymptomsListBox.Items = symptoms.Symptom; % display array extracted from "Symptom" variable
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            selectedArray = app.SymptomsListBox.Value;
            diagnosis = GeneralCommunicator.returnPrediction(selectedArray);
            PredictionsApp(diagnosis);            
        end

        % Value changed function: SymptomsListBox
        function SymptomsListBoxValueChanged(app, event)
            selected = app.SymptomsListBox.Value;
            app.SelectedSymptomsListBox.Items = selected;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SymptomsListBoxLabel = uilabel(app.UIFigure);
            app.SymptomsListBoxLabel.Text = 'Symptoms';
            app.SymptomsListBox = uilistbox(app.UIFigure);
            app.SymptomsListBox.Items = {''};
            app.SymptomsListBox.Multiselect = 'on';
            app.SymptomsListBox.ValueChangedFcn = createCallbackFcn(app, @SymptomsListBoxValueChanged, true);
            app.SymptomsListBox.Tooltip = {'hold "Ctrl" to multiselect'};
            app.SymptomsListBox.Value = {};
            app.SelectedSymptomsListBoxLabel = uilabel(app.UIFigure);
            app.SelectedSymptomsListBoxLabel.Text = 'Selected Symptoms';
            app.SelectedSymptomsListBox = uilistbox(app.UIFigure);
            app.SelectedSymptomsListBox.Items = {};
            app.SelectedSymptomsListBox.Tooltip = {''};
            app.SelectedSymptomsListBox.Value = {};
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeneralApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end